classdef g3_ss_miniproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        G3SIGNALSYSTEMTIMEREVERSALMINIPROJECTLabel  matlab.ui.control.Label
        ContinuousTimeSignalLabel    matlab.ui.control.Label
        DiscreteTimeSignalLabel      matlab.ui.control.Label
        PlotSintSignalButton         matlab.ui.control.Button
        PlotSinnSignalButton         matlab.ui.control.Button
        PlotCostSignalButton         matlab.ui.control.Button
        PlotCosnSignalButton         matlab.ui.control.Button
        PlotTantSignalButton         matlab.ui.control.Button
        PlotTannSignalButton         matlab.ui.control.Button
        PlotImpulseSignalButton      matlab.ui.control.Button
        PlotImpulseSignalButton_2    matlab.ui.control.Button
        PlotUnitStepSignalButton     matlab.ui.control.Button
        PlotUnitStepSignalButton_2   matlab.ui.control.Button
        PlotRampSignalButton         matlab.ui.control.Button
        PlotRampSignalButton_2       matlab.ui.control.Button
        PlotParabolicSignalButton    matlab.ui.control.Button
        PlotParabolicSignalButton_2  matlab.ui.control.Button
        PlotSignumSignalButton       matlab.ui.control.Button
        PlotSignumSignalButton_2     matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotSintSignalButton
        function PlotSintSignalButtonPushed(app, event)
            x = -10:0.1:10;
            y2=sin(x);
            p = plot(app.UIAxes,-x,y2,'--go');
            p.Color='g';
            y1=sin(x);
            p1 = plot(app.UIAxes2,x,y1,':');
            p1.Color='b';
          
            
        end

        % Button pushed function: PlotSinnSignalButton
        function PlotSinnSignalButtonPushed(app, event)
            x = linspace(-2*pi,2*pi,13);
            y1=sin(x);
            s = stem(app.UIAxes,-x,y1,'filled');
            s.Color = 'g';
            y2=sin(x);
            s2 = stem(app.UIAxes2,x,y2,'filled');
            s2.Color = 'r';
        end

        % Button pushed function: PlotCostSignalButton
        function PlotCostSignalButtonPushed(app, event)
            x = -10:0.1:10;
            y2=cos(x);
            p = plot(app.UIAxes,-x,y2,'--go');
            p.Color='g';
            y1=cos(x);
            p1= plot(app.UIAxes2,x,y1,':');
            p1.Color='b';
        end

        % Button pushed function: PlotCosnSignalButton
        function PlotCosnSignalButtonPushed(app, event)
            x = linspace(-2*pi,2*pi,13);
            y1=cos(x);
            s = stem(app.UIAxes,-x,y1,'filled');
            s.Color = 'g';
            y2=cos(x);
            s2 = stem(app.UIAxes2,x,y2,'filled');
            s2.Color = 'r';
        end

        % Button pushed function: PlotTantSignalButton
        function PlotTantSignalButtonPushed(app, event)
            x = -10:0.1:10;
            y2=tan(x);
            p = plot(app.UIAxes,-x,y2,'--o');
            p.Color='g';
            y1=tan(x);
            p1 = plot(app.UIAxes2,x,y1,':');
            p1.Color='b';
        end

        % Button pushed function: PlotTannSignalButton
        function PlotTannSignalButtonPushed(app, event)
            x = linspace(-2*pi,2*pi,13);
            y1=tan(x);
            s = stem(app.UIAxes,-x,y1,'filled');
            s.Color='g';
            y2=tan(x);
            s2 = stem(app.UIAxes2,x,y2,'filled');
            s2.Color='r';
        end

        % Button pushed function: PlotImpulseSignalButton
        function PlotImpulseSignalButtonPushed(app, event)
            t = -1:0.01:1;
            impulse = t==0;
            p = plot(app.UIAxes2,t,impulse);
            p.Color='b';
            p1 = plot(app.UIAxes,-t,impulse);
            p1.Color='g';
        end

        % Button pushed function: PlotImpulseSignalButton_2
        function PlotImpulseSignalButton_2Pushed(app, event)
            t = -1:1:1;
            impulse = t==0;
            s = stem(app.UIAxes2,t,impulse,'filled');
            s.Color='r';
            s1 = stem(app.UIAxes,-t,impulse,'filled');
            s1.Color='g';
        end

        % Button pushed function: PlotUnitStepSignalButton
        function PlotUnitStepSignalButtonPushed(app, event)
            t = -3:0.01:3;
            unitstep = t>=0;
            p = plot(app.UIAxes2,t,unitstep);
            p.Color='b';
            p1 = plot(app.UIAxes,-t,unitstep);
            p1.Color='g';
        end

        % Button pushed function: PlotUnitStepSignalButton_2
        function PlotUnitStepSignalButton_2Pushed(app, event)
            t = -3:1:3;
            unitstep = t>=0;
            s = stem(app.UIAxes2,t,unitstep,'filled');
            s.Color='r';
            s1 = stem(app.UIAxes,-t,unitstep,'filled');
            s1.Color='g';
        end

        % Button pushed function: PlotRampSignalButton
        function PlotRampSignalButtonPushed(app, event)
            t = -3:0.01:3;
            u = t>=0;
            ramp = t.*u;
            p = plot(app.UIAxes2,t,ramp);
            p.Color='b';
            p1 = plot(app.UIAxes,-t,ramp);
            p1.Color='g';
        end

        % Button pushed function: PlotRampSignalButton_2
        function PlotRampSignalButton_2Pushed(app, event)
            t = -3:1:3;
            u = t>=0;
            ramp = t.*u;
            s = stem(app.UIAxes2,t,ramp,'filled');
            s.Color='r';
            s1 = stem(app.UIAxes,-t,ramp,'filled');
            s1.Color='g';
        end

        % Button pushed function: PlotParabolicSignalButton
        function PlotParabolicSignalButtonPushed(app, event)
            t = -5:0.01:5;
            u = t>=0;
            para = t.^2.*u;
            p = plot(app.UIAxes2,t,para);
            p.Color='b';
            p1 = plot(app.UIAxes,-t,para);
            p1.Color='g';
        end

        % Button pushed function: PlotParabolicSignalButton_2
        function PlotParabolicSignalButton_2Pushed(app, event)
            t = -5:1:5;
            u = t>=0;
            para = t.^2.*u;
            s = stem(app.UIAxes2,t,para,'filled');
            s.Color='r';
            s1 = stem(app.UIAxes,-t,para,'filled');
            s1.Color='g';
        end

        % Button pushed function: PlotSignumSignalButton
        function PlotSignumSignalButtonPushed(app, event)
            t = -3:0.01:3;
            u = t>=0;
            sgn = 2.*u - 1;
            p = plot(app.UIAxes2,t,sgn);
            p.Color='b';
            p1 = plot(app.UIAxes,-t,sgn);
            p1.Color='g';
        end

        % Button pushed function: PlotSignumSignalButton_2
        function PlotSignumSignalButton_2Pushed(app, event)
            t = -3:1:3;
            u = t>=0;
            sgn = 2.*u - 1;
            s = stem(app.UIAxes2,t,sgn,'filled');
            s.Color='r';
            s1 = stem(app.UIAxes,-t,sgn,'filled');
            s1.Color='g';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.G3SIGNALSYSTEMTIMEREVERSALMINIPROJECTLabel = uilabel(app.UIFigure);
            app.G3SIGNALSYSTEMTIMEREVERSALMINIPROJECTLabel.Text = {'G3 SIGNAL & SYSTEM'; 'TIME REVERSAL MINIPROJECT'};
            app.ContinuousTimeSignalLabel = uilabel(app.UIFigure);
            app.ContinuousTimeSignalLabel.Text = 'Continuous Time Signal';
            app.DiscreteTimeSignalLabel = uilabel(app.UIFigure);
            app.DiscreteTimeSignalLabel.Text = 'Discrete Time Signal';
            app.PlotSintSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotSintSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSintSignalButtonPushed, true);
            app.PlotSintSignalButton.Text = 'Plot Sin(t) Signal';
            app.PlotSinnSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotSinnSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSinnSignalButtonPushed, true);
            app.PlotSinnSignalButton.Text = 'Plot Sin(n) Signal';
            app.PlotCostSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotCostSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCostSignalButtonPushed, true);
            app.PlotCostSignalButton.Text = 'Plot Cos(t) Signal';
            app.PlotCosnSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotCosnSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCosnSignalButtonPushed, true);
            app.PlotCosnSignalButton.Text = {'Plot Cos(n) Signal'; ''};
            app.PlotTantSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotTantSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTantSignalButtonPushed, true);
            app.PlotTantSignalButton.Text = 'Plot Tan(t) Signal';
            app.PlotTannSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotTannSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTannSignalButtonPushed, true);
            app.PlotTannSignalButton.Text = 'Plot Tan(n) Signal';
            app.PlotImpulseSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotImpulseSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotImpulseSignalButtonPushed, true);
            app.PlotImpulseSignalButton.Text = 'Plot Impulse Signal';
            app.PlotImpulseSignalButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotImpulseSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotImpulseSignalButton_2Pushed, true);
            app.PlotImpulseSignalButton_2.Text = 'Plot Impulse Signal';
            app.PlotUnitStepSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotUnitStepSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotUnitStepSignalButtonPushed, true);
            app.PlotUnitStepSignalButton.Text = 'Plot UnitStep Signal';
            app.PlotUnitStepSignalButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotUnitStepSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotUnitStepSignalButton_2Pushed, true);
            app.PlotUnitStepSignalButton_2.Text = 'Plot UnitStep Signal';
            app.PlotRampSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotRampSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRampSignalButtonPushed, true);
            app.PlotRampSignalButton.Text = 'Plot Ramp Signal';
            app.PlotRampSignalButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotRampSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotRampSignalButton_2Pushed, true);
            app.PlotRampSignalButton_2.Text = 'Plot Ramp Signal';
            app.PlotParabolicSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotParabolicSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotParabolicSignalButtonPushed, true);
            app.PlotParabolicSignalButton.Text = 'Plot Parabolic Signal';
            app.PlotParabolicSignalButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotParabolicSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotParabolicSignalButton_2Pushed, true);
            app.PlotParabolicSignalButton_2.Text = 'Plot Parabolic Signal';
            app.PlotSignumSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotSignumSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSignumSignalButtonPushed, true);
            app.PlotSignumSignalButton.Text = 'Plot Signum Signal';
            app.PlotSignumSignalButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotSignumSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotSignumSignalButton_2Pushed, true);
            app.PlotSignumSignalButton_2.Text = 'Plot Signum Signal';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reversed Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Input Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = g3_ss_miniproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end